classdef FYP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        ThemeTool                       matlab.ui.container.toolbar.PushTool
        HelpTool                        matlab.ui.container.toolbar.PushTool
        DemoTool                        matlab.ui.container.toolbar.PushTool
        RefreshTool                     matlab.ui.container.toolbar.PushTool
        TabGroup                        matlab.ui.container.TabGroup
        RentEstimatorTab                matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        PredictedPriceEditField         matlab.ui.control.NumericEditField
        PredictedPriceEditField_2Label  matlab.ui.control.Label
        FurnishedDropDown               matlab.ui.control.DropDown
        FurnishedDropDownLabel          matlab.ui.control.Label
        PropertyInformationLabel        matlab.ui.control.Label
        SizesqftEditField               matlab.ui.control.NumericEditField
        SizesqftEditFieldLabel          matlab.ui.control.Label
        RoomSpinner                     matlab.ui.control.Spinner
        RoomSpinnerLabel                matlab.ui.control.Label
        RegionDropDown                  matlab.ui.control.DropDown
        RegionDropDownLabel             matlab.ui.control.Label
        LocationDropDown                matlab.ui.control.DropDown
        LocationDropDownLabel           matlab.ui.control.Label
        BathroomSpinner                 matlab.ui.control.Spinner
        BathroomSpinnerLabel            matlab.ui.control.Label
        ParkingSpinner                  matlab.ui.control.Spinner
        ParkingSpinnerLabel             matlab.ui.control.Label
        PredictPrice                    matlab.ui.control.StateButton
        Tree                            matlab.ui.container.CheckBoxTree
        FacilitiesNode                  matlab.ui.container.TreeNode
        barbeque_areaNode               matlab.ui.container.TreeNode
        club_houseNode                  matlab.ui.container.TreeNode
        gymnasiumNode                   matlab.ui.container.TreeNode
        jogging_trackNode               matlab.ui.container.TreeNode
        liftNode                        matlab.ui.container.TreeNode
        minimartNode                    matlab.ui.container.TreeNode
        multipurpose_hallNode           matlab.ui.container.TreeNode
        playgroundNode                  matlab.ui.container.TreeNode
        saunaNode                       matlab.ui.container.TreeNode
        securityNode                    matlab.ui.container.TreeNode
        washing_machineNode             matlab.ui.container.TreeNode
        squash_courtNode                matlab.ui.container.TreeNode
        swimming_poolNode               matlab.ui.container.TreeNode
        tennis_courtNode                matlab.ui.container.TreeNode
        aircondNode                     matlab.ui.container.TreeNode
        internetNode                    matlab.ui.container.TreeNode
        InvestmentAnalysisDashboardTab  matlab.ui.container.Tab
        GridLayout7                     matlab.ui.container.GridLayout
        Panel_8                         matlab.ui.container.Panel
        NOIValueLabel_8                 matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        NOIValueLabel_7                 matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        NOIValueLabel_6                 matlab.ui.control.Label
        Panel_7                         matlab.ui.container.Panel
        NOIValueLabel_5                 matlab.ui.control.Label
        Panel_9                         matlab.ui.container.Panel
        NOIValueLabel_4                 matlab.ui.control.Label
        Panel_14                        matlab.ui.container.Panel
        NOIValueLabel_3                 matlab.ui.control.Label
        Panel_6                         matlab.ui.container.Panel
        NOIValueLabel_2                 matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        NOIValueLabel                   matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        FuzzyResultLabel                matlab.ui.control.Label
        ExportPdfButton                 matlab.ui.control.Button
        SuggestionTextArea              matlab.ui.control.TextArea
        SuggestionTextAreaLabel         matlab.ui.control.Label
        HoldingYearsEditField           matlab.ui.control.NumericEditField
        HoldingYearsEditFieldLabel      matlab.ui.control.Label
        TotalCashInvestedEditField      matlab.ui.control.NumericEditField
        TotalCashInvestedEditFieldLabel  matlab.ui.control.Label
        LoanAmountEditField             matlab.ui.control.NumericEditField
        TotalLoanAmountEditFieldLabel   matlab.ui.control.Label
        MonthlyPaymentEditField         matlab.ui.control.NumericEditField
        MonthlyPaymentEditFieldLabel    matlab.ui.control.Label
        OperatingExpensesEditField      matlab.ui.control.NumericEditField
        OperatingExpensesyrLabel        matlab.ui.control.Label
        MarketPriceEditField            matlab.ui.control.NumericEditField
        MarketPriceEditFieldLabel       matlab.ui.control.Label
        PurchasePriceEditField          matlab.ui.control.NumericEditField
        PurchasePriceEditFieldLabel     matlab.ui.control.Label
        MonthlyRentEditField            matlab.ui.control.NumericEditField
        MonthlyRentEditFieldLabel       matlab.ui.control.Label
        Button_3                        matlab.ui.control.StateButton
        Panel_12                        matlab.ui.container.Panel
        NOIValueLabel_12                matlab.ui.control.Label
        Panel_11                        matlab.ui.container.Panel
        NOIValueLabel_11                matlab.ui.control.Label
        Panel_13                        matlab.ui.container.Panel
        NOIValueLabel_10                matlab.ui.control.Label
        Panel_10                        matlab.ui.container.Panel
        NOIValueLabel_9                 matlab.ui.control.Label
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes7_2                       matlab.ui.control.UIAxes
        UIAxes7_3                       matlab.ui.control.UIAxes
        UIAxes7_4                       matlab.ui.control.UIAxes
        UIAxes7_5                       matlab.ui.control.UIAxes
        UIAxes7_6                       matlab.ui.control.UIAxes
        NearbyTransportTab              matlab.ui.container.Tab
        GridLayout9                     matlab.ui.container.GridLayout
        BuildingNameEditField           matlab.ui.control.EditField
        BuildingNameEditFieldLabel      matlab.ui.control.Label
        SearchButton                    matlab.ui.control.Button
        SearchStatusLabel               matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = private)
        IsDarkTheme logical = false;
        pyRentModel
        LocationTable
        FISRules
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if isdeployed
                baseDir = ctfroot;
            else
                baseDir = fileparts(mfilename('fullpath'));
            end

            fisPath   = fullfile(baseDir, 'FISRules.fis');
            modelPath = fullfile(baseDir, 'Models', 'best_xgboost_model.pkl');
            csvPath   = fullfile(baseDir, 'data', 'location_id_mapping.csv');

            if ~exist(fisPath,'file')
                hits = dir(fullfile(baseDir, '**', 'FISRules.fis'));   
                if ~isempty(hits)
                    fisPath = fullfile(hits(1).folder, hits(1).name);
                end
            end

            % ========= 3) Validate key files (so you know exactly what missing) =========
            if ~exist(fisPath,'file')
                uialert(app.UIFigure, "Missing: " + fisPath + newline + ...
                    "Fix: Put FISRules.fis next to exe (or one folder above).", "Missing File");
                return;
            end
            if ~exist(modelPath,'file')
                uialert(app.UIFigure, "Missing: " + modelPath + newline + ...
                    "Fix: Ensure Models\best_xgboost_model.pkl exists next to exe.", "Missing File");
                return;
            end
            if ~exist(csvPath,'file')
                uialert(app.UIFigure, "Missing: " + csvPath + newline + ...
                    "Fix: Ensure data\location_id_mapping.csv exists next to exe.", "Missing File");
                return;
            end

            % ========= 4) Load FIS / Model / CSV =========
            app.FISRules = readfis(fisPath);

            joblib = py.importlib.import_module('joblib');
            app.pyRentModel = joblib.load(modelPath);

            T = readtable(csvPath);
            app.LocationDropDown.Items     = T.location_name;
            app.LocationDropDown.ItemsData = T.location;

            app.UIFigure.WindowState = 'maximized';
            app.IsDarkTheme = false;
            comps = findall(app.UIFigure, '-property', 'FontSize');

            for c = 1:length(comps)

                if isa(comps(c), 'matlab.ui.container.Panel')
                    comps(c).FontSize = 9;
                elseif isa(comps(c), 'matlab.ui.container.Label')
                    comps(c).FontSize = 17;
                else
                    comps(c).FontSize = 12;
                end

            end


            app.UIFigure.WindowState = 'maximized';

            defaultBuildingName = "Kuala Lumpur City Centre";
            app.showMap(defaultBuildingName);


            app.LocationDropDown.Items = T.location_name;

            app.LocationDropDown.ItemsData = T.location;
            app.RegionDropDown.Items = ["Kuala Lumpur","Penang","Selangor"];
            app.RegionDropDown.ItemsData = [0, 1, 2];
            app.FurnishedDropDown.Items = ["Not Furnished","Partially Furnished","Fully Furnished"];
            app.FurnishedDropDown.ItemsData = [1, 2, 0];

        end

        % Callback function: ThemeTool
        function ThemeToolClicked(app, event)
            app.IsDarkTheme = ~app.IsDarkTheme;

            if app.IsDarkTheme
                % ---- Dark Mode ----
                app.UIFigure.Color = [0.15 0.15 0.18];

                comps = findall(app.UIFigure, '-property','FontColor');
                for k = 1:numel(comps)
                    comps(k).FontColor = [1 1 1];
                end

                axesList = findall(app.UIFigure, 'Type','matlab.ui.control.UIAxes');
                for k = 1:numel(axesList)
                    axesList(k).Color     = [0.18 0.18 0.22];
                    axesList(k).XColor    = [1 1 1];
                    axesList(k).YColor    = [1 1 1];
                    axesList(k).GridColor = [0.6 0.6 0.6];
                end
            else
                % ---- Light Mode ----
                app.UIFigure.Color = [0.94 0.94 0.94];

                comps = findall(app.UIFigure, '-property','FontColor');
                for k = 1:numel(comps)
                    comps(k).FontColor = [0 0 0];
                end

                axesList = findall(app.UIFigure, 'Type','matlab.ui.control.UIAxes');
                for k = 1:numel(axesList)
                    axesList(k).Color     = [1 1 1];
                    axesList(k).XColor    = [0 0 0];
                    axesList(k).YColor    = [0 0 0];
                    axesList(k).GridColor = [0.4 0.4 0.4];
                end
            end

        end

        % Callback function: RefreshTool
        function RefreshToolClicked(app, event)
            %% Rent Estimator Tab
            % Size
            app.SizesqftEditField.Value = 0;

            % Spinners
            app.RoomSpinner.Value      = 0;
            app.ParkingSpinner.Value   = 0;
            app.BathroomSpinner.Value  = 0;

            % Region & Location
            if ~isempty(app.FurnishedDropDown.ItemsData)
                app.FurnishedDropDown.Value = app.FurnishedDropDown.ItemsData(1);
            else
                app.FurnishedDropDown.Value = app.FurnishedDropDown.Items{1};
            end
            if ~isempty(app.RegionDropDown.ItemsData)
                app.RegionDropDown.Value = app.RegionDropDown.ItemsData(1);
            else
                app.RegionDropDown.Value = app.RegionDropDown.Items{1};
            end

            if ~isempty(app.LocationDropDown.ItemsData)
                app.LocationDropDown.Value = app.LocationDropDown.ItemsData(1);
            else
                app.LocationDropDown.Value = app.LocationDropDown.Items{1};
            end

            try
                app.Tree.SelectedNodes = [];
            catch
            end

            app.PredictedPriceEditField.Value = 0;

            %% Clear Investment Analysis Tab
            app.MonthlyRentEditField.Value        = 0;
            app.PurchasePriceEditField.Value      = 0;
            app.MarketPriceEditField.Value        = 0;
            app.OperatingExpensesEditField.Value  = 0;
            app.MonthlyPaymentEditField.Value     = 0;
            app.LoanAmountEditField.Value         = 0;
            app.TotalCashInvestedEditField.Value  = 0;
            app.HoldingYearsEditField.Value       = 0;

            app.NOIValueLabel.Text         = "-";
            app.NOIValueLabel_2.Text       = "Cash Flow: -";
            app.NOIValueLabel_3.Text       = "Net Rental Yield: -";
            app.NOIValueLabel_4.Text       = "Cap Rate: -";
            app.NOIValueLabel_5.Text       = "Cash-on-Cash ROI: -";
            app.NOIValueLabel_6.Text       = "Equity Multiple: -";
            app.NOIValueLabel_7.Text       = "Payback: -";
            app.NOIValueLabel_8.Text       = "Total ROI: -";
            app.NOIValueLabel_9.Text       = "DSCR: -";
            app.NOIValueLabel_10.Text      = "LTV: -";
            app.NOIValueLabel_11.Text      = "Break-even Occupancy: -";
            app.NOIValueLabel_12.Text      = "Annual Appreciation : -";


            cla(app.UIAxes7);     % Annual Cash Flow Trend
            cla(app.UIAxes7_2);   % Equity Growth
            cla(app.UIAxes7_3);   % DSCR bar
            cla(app.UIAxes7_4);   % LTV bar
            cla(app.UIAxes7_5);   % Break-even donut
            cla(app.UIAxes7_6);   % OER donut

            app.SuggestionTextArea.Value = {''};
            app.FuzzyResultLabel.Text = "";

            app.BuildingNameEditField.Value = "";
            app.UITable.Data                = {};

        end

        % Callback function: DemoTool
        function DemoToolClicked(app, event)
            app.SizesqftEditField.Value      = 850;   % 850 sqft
            app.RoomSpinner.Value        = 3;
            app.ParkingSpinner.Value     = 1;
            app.BathroomSpinner.Value    = 2;

            furnishCode   = 0;
            regionCode   = 0;
            locationCode = 30;
            app.FurnishedDropDown.Value   = furnishCode;

            app.RegionDropDown.Value   = regionCode;
            app.LocationDropDown.Value = locationCode;

            selectedNames = ["gymnasium","swimming_pool","security","lift"];

            %% Investment Analysis Tab
            app.PurchasePriceEditField.Value      = 320000;
            app.MarketPriceEditField.Value        = 450000;
            app.OperatingExpensesEditField.Value  = 3600;   % per year
            app.MonthlyPaymentEditField.Value     = 1100;
            app.LoanAmountEditField.Value         = 200000;
            app.TotalCashInvestedEditField.Value  = 120000;
            app.HoldingYearsEditField.Value       = 8;


        end

        % Callback function: HelpTool
        function HelpToolClicked(app, event)
            information = [
                "Rental Price Estimator & Investment Analysis Tool"
                ""
                "This tool is designed to help you make smarter real-estate decisions."
                "Whether you're an investor, a homeowner, or just curious about market trends,"
                "this tool gives you accurate rental estimates and investment analytics in one place."
                ""
                "■ Rent Estimator — Tab 1"
                "Predict rental price accordingly as a reference for investing or renting."
                "Just enter your property details—size, rooms, parking, bathrooms, furnishing status,"
                "region, location, and facilities—and get an instant predicted rental price."
                "Once you're ready, simply click the Predict button to view your results."
                ""
                "■ Investment Analysis Dashboard — Tab 2"
                "One scroll brings you into a full investment intelligence suite."
                "Simply input your rent, purchase price, market value, monthly expenses,"
                "loan terms, and holding period."
                "You’ll get a full breakdown of investment KPIs, including:"
                "NOI, Cash Flow, Net Rental Yield, Cap Rate, Cash-on-Cash ROI, Total ROI,"
                "Equity Multiple, Payback Period, DSCR, LTV, Operating Expense Ratio,"
                "and Break-Even Occupancy Rate."
                "A complete dashboard designed to help you evaluate real-estate ROI with confidence."
                ""
                "■ Nearby Transport — Tab 3"
                "Find nearby public transport instantly."
                "Just enter your building name and the tool shows the closest station"
                "and the walking distance in meters—perfect for renters, buyers,"
                "and investors who value accessibility."
                ];

            uialert(app.UIFigure, strjoin(information,newline), ...
                "About This App", ...
                'Icon','info');
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            buildingName = strtrim(app.BuildingNameEditField.Value);

            if isempty(buildingName)
                uialert(app.UIFigure, 'Enter a building name before searching.', 'Invalid Input');
                return;
            end

            % call the map function
            app.showMap(buildingName);
        end

        function showMap(app, buildingName)

            app.SearchStatusLabel.Text = 'Searching building and nearby public transport...';
            drawnow;

            % 0) Configuration
            radiusList = [600 1200 2500 5000 8000];   % meters
            maxCandidatesRail = 18;
            maxCandidatesBus  = 18;           %filter threshold (treated as "car time" in your rule)
            maxOSRMPoints     = 30;           % total points in OSRM table including building
            maxCarMin        = 20;            % filter threshold (treated as "car time" in your rule)

            optsGeo = weboptions('UserAgent','MATLAB Student Project', ...
                'Timeout',8,'ContentType','json');

            % 1) Geocode building (Nominatim fallback servers)
            geoServers = {
                'https://nominatim.openstreetmap.org/search'
                'https://nominatim.openstreetmap.fr/search'
                };

            q = sprintf('%s, Malaysia', strtrim(buildingName));

            location = [];
            geoErr = [];
            isSuccess = false;

            for i = 1:numel(geoServers)
                try
                    loc = webread(geoServers{i}, 'q', q, 'format','json','limit',1, optsGeo);
                    if ~isempty(loc)
                        location = loc;
                        isSuccess = true;
                        break;
                    end
                catch ME
                    geoErr = ME;
                end
            end

            if ~isSuccess || isempty(location)
                msg = "Building not found / geocode failed.";
                if ~isempty(geoErr), msg = msg + newline + "Last error: " + geoErr.message; end
                uialert(app.UIFigure, msg, 'Geocode Error');
                app.SearchStatusLabel.Text = 'Search failed.';
                return;
            end

            bLat = str2double(location(1).lat);
            bLon = str2double(location(1).lon);

            % 2) Overpass: query rail first, then bus, increasing radius
            overpassServers = {
                'https://overpass-api.de/api/interpreter'
                'https://overpass.kumi.systems/api/interpreter'
                'https://overpass.openstreetmap.fr/api/interpreter'
                'https://overpass.osm.ch/api/interpreter'
                'https://overpass.nchc.org.tw/api/interpreter'
                };

            optsOSM = weboptions('RequestMethod','post', ...
                'MediaType','application/x-www-form-urlencoded', ...
                'ContentType','json','Timeout',18);

            railCand = [];  % struct array: .name .type .lat .lon .priority
            busCand  = [];

            for r = radiusList

                % (A) Rail candidates
                if numel(railCand) < 10
                    newRail = queryOverpassRail(overpassServers, optsOSM, bLat, bLon, r);
                    railCand = mergeUniqueStops(railCand, newRail);
                    railCand = limitStops(railCand, maxCandidatesRail);
                end

                % (B) Bus candidates (fill when rail is insufficient)
                if numel(railCand) < 3 || numel(busCand) < 3
                    newBus = queryOverpassBus(overpassServers, optsOSM, bLat, bLon, r);
                    busCand = mergeUniqueStops(busCand, newBus);
                    busCand = limitStops(busCand, maxCandidatesBus);
                end

                % Stop expanding if we have enough candidates to evaluate via OSRM
                total = numel(railCand) + numel(busCand);
                if total >= 6 || r == radiusList(end)
                    break;
                end
            end

            if isempty(railCand) && isempty(busCand)
                uialert(app.UIFigure, 'No nearby public transport candidates found.', 'No Stops');
                app.SearchStatusLabel.Text = 'Search completed (none found).';
                return;
            end

            % 3) Build OSRM candidate list: keep rail first, then fill bus, cap total points
            keepN = min(numel(railCand) + numel(busCand), maxOSRMPoints-1);

            minRailKeep = 12;
            railKeepN = min([numel(railCand), minRailKeep, keepN]);
            busKeepN  = max(0, keepN - railKeepN);

            % Sort rail by rough (Haversine) distance
            if ~isempty(railCand)
                dRail = roughDistanceMeters(bLat, bLon, [railCand.lat]', [railCand.lon]');
                [~, idxR] = sort(dRail);
                railSorted = railCand(idxR);
            else
                railSorted = railCand;
            end

            % Sort rail by rough distance
            if ~isempty(busCand)
                dBus = roughDistanceMeters(bLat, bLon, [busCand.lat]', [busCand.lon]');
                [~, idxB] = sort(dBus);
                busSorted = busCand(idxB);
            else
                busSorted = busCand;
            end

            railPick = railSorted(1:railKeepN);
            busPick = busSorted(1:min(busKeepN, numel(busSorted)));
            allCand = [railPick(:); busPick(:)];

            % 4) OSRM TABLE: get distance + duration (you treat duration as "car time")
            [osrmDistM, osrmDurS, okMask] = osrmTableFoot(bLat, bLon, allCand);

            WALK_SPEED_M_PER_MIN = 85;   % ≈ 5 km/h
            CAR_SPEED_M_PER_MIN  = 1000;  % 60 km/h

            for i = 1:numel(allCand)
                allCand(i).osrmDistM  = osrmDistM(i);
                allCand(i).osrmRawMin = osrmDurS(i) / 60;

                if ~isnan(allCand(i).osrmDistM) && allCand(i).osrmDistM > 0
                    % walking time from distance
                    allCand(i).osrmWalkMin = allCand(i).osrmDistM / WALK_SPEED_M_PER_MIN;

                    % car time from distance
                    allCand(i).osrmCarMin  = allCand(i).osrmDistM / CAR_SPEED_M_PER_MIN;
                else
                    allCand(i).osrmWalkMin = NaN;
                    allCand(i).osrmCarMin  = NaN;
                end
                allCand(i).osrmOK = okMask(i);
            end

            valid = arrayfun(@(s) s.osrmOK && ~isnan(s.osrmRawMin) && s.osrmRawMin <= maxCarMin, allCand);
            candValid = allCand(valid);

            if isempty(candValid)
                uialert(app.UIFigure, sprintf('No public transport within %d minutes driving time.', maxCarMin), ...
                    'No Nearby Stops');
                app.SearchStatusLabel.Text = 'Search completed (no nearby stops).';
                return;
            end

            % 5) Pick top 3: rail preferred; name bonus for MRT/LRT/etc
            candValid = scoreAndSort(candValid);
            picked = pickTop3PreferRailThenBus(candValid);

            if isempty(picked)
                uialert(app.UIFigure, 'No suitable stops after filtering.', 'No Suitable Stops');
                app.SearchStatusLabel.Text = 'Search completed.';
                return;
            end

            [~, o] = sort([picked.osrmDistM]);
            picked = picked(o);

            % 6) Draw map points (no routes)
            persistent geoAx
            if isempty(geoAx) || ~isvalid(geoAx)

                if isprop(app,'UIAxes') && isvalid(app.UIAxes)
                    delete(app.UIAxes);
                end

                geoAx = geoaxes(app.GridLayout9);
                geoAx.Layout.Row = 1;
                geoAx.Layout.Column = [1 7];

            end
            ax = geoAx;
            ax = geoAx;
            geobasemap(ax,'streets');
            cla(ax);
            hold(ax,'on');

            geoplot(ax, bLat, bLon, 'ro', 'MarkerSize', 6, 'LineWidth', 1.5);
            text(ax, bLat, bLon, ['  ' buildingName], 'FontWeight','bold','Color','r');

            for i = 1:numel(picked)
                geoplot(ax, picked(i).lat, picked(i).lon, 'bo', 'MarkerSize', 6, 'LineWidth', 1.5);
                label = picked(i).name;
                if isempty(label) || strcmp(label,'(no name)')
                    label = sprintf('%s', picked(i).type);
                end
                text(ax, picked(i).lat, picked(i).lon, ['  ' label], 'FontSize',8, 'Color','b');
            end

            % zoom
            selLat = [picked.lat]';
            selLon = [picked.lon]';
            latlim = [min([bLat; selLat]) max([bLat; selLat])];
            lonlim = [min([bLon; selLon]) max([bLon; selLon])];
            padding = 0.001;
            geolimits(ax, latlim + [-padding padding], lonlim + [-padding padding]);
            hold(ax,'off');

            % 7) Fill table (distance + car time + walking time)
            k = numel(picked);
            rank = (1:k)';

            nameTypeStr = strings(k,1);
            distM  = zeros(k,1);
            carMin = zeros(k,1);
            walkMin = zeros(k,1);

            for i = 1:k
                nameTypeStr(i) = string(picked(i).name) + "  (" + string(picked(i).type) + ")";
                distM(i)  = picked(i).osrmDistM;
                carMin(i) = picked(i).osrmCarMin;
                walkMin(i)= picked(i).osrmWalkMin;
            end

            tableData = [ ...
                num2cell(rank), ...
                cellstr(nameTypeStr), ...
                num2cell(round(distM)), ...
                num2cell(round(carMin)), ...
                num2cell(round(walkMin)) ...
                ];

            app.UITable.Data = tableData;
            app.SearchStatusLabel.Text = 'Search completed.';

            % ===================== Helper Functions =====================
            function stops = queryOverpassRail(servers, opts, lat, lon, r)
                qRail = sprintf([ ...
                    '[out:json][timeout:20];(' ...
                    'node(around:%d,%.6f,%.6f)["railway"="station"];' ...
                    'way(around:%d,%.6f,%.6f)["railway"="station"];' ...
                    'node(around:%d,%.6f,%.6f)["station"~"subway|light_rail|monorail|train"];' ...
                    'node(around:%d,%.6f,%.6f)["railway"="subway_entrance"];' ...
                    'node(around:%d,%.6f,%.6f)["public_transport"="station"];' ...
                    'way(around:%d,%.6f,%.6f)["public_transport"="station"];' ...
                    'node(around:%d,%.6f,%.6f)["railway"~"halt|tram_stop|stop"];' ...
                    ');out center;'], ...
                    r, lat, lon, ...
                    r, lat, lon, ...
                    r, lat, lon, ...
                    r, lat, lon, ...
                    r, lat, lon, ...
                    r, lat, lon, ...
                    r, lat, lon);

                data = callOverpass(servers, opts, qRail);
                stops = parseStopsFromOverpass(data, 2);
            end

            function stops = queryOverpassBus(servers, opts, lat, lon, r)
                qBus = sprintf([ ...
                    '[out:json][timeout:10];(' ...
                    'node(around:%d,%.6f,%.6f)["highway"="bus_stop"];' ...
                    'node(around:%d,%.6f,%.6f)["public_transport"="platform"]["bus"="yes"];' ...
                    ');out body;'], ...
                    r, lat, lon, ...
                    r, lat, lon);

                data = callOverpass(servers, opts, qBus);
                stops = parseStopsFromOverpass(data, 1);
            end

            function data = callOverpass(servers, opts, query)
                data = [];
                lastErr = [];
                for ii = 1:numel(servers)
                    try
                        tmp = webwrite(servers{ii}, 'data', query, opts);
                        if isfield(tmp,'elements')
                            data = tmp;
                            return;
                        end
                    catch ME
                        lastErr = ME;
                    end
                end
                if isempty(data)
                    msg = "All Overpass servers failed.";
                    if ~isempty(lastErr), msg = msg + newline + "Last error: " + lastErr.message; end
                    uialert(app.UIFigure, msg, "Overpass Error");
                end
            end

            function stops = parseStopsFromOverpass(data, prio)
                stops = struct('id',{},'name',{},'type',{},'lat',{},'lon',{},'priority',{});
                if isempty(data) || ~isfield(data,'elements') || isempty(data.elements)
                    return;
                end
                elems = data.elements;

                for ii = 1:numel(elems)
                    e = elems(ii);

                    lat = NaN; lon = NaN;
                    if isfield(e,'lat') && isfield(e,'lon')
                        lat = e.lat; lon = e.lon;
                    elseif isfield(e,'center')
                        lat = e.center.lat; lon = e.center.lon;
                    end
                    if isnan(lat), continue; end

                    if ~isfield(e,'tags'), continue; end
                    tags = e.tags;

                    % Require a name to avoid unknown points
                    if ~isfield(tags,'name') || isempty(strtrim(tags.name))
                        continue;
                    end
                    nm = strtrim(tags.name);
                    nmLower = lower(string(nm));

                    % Determine type from tags
                    tp = 'stop';
                    if isfield(tags,'station'),  tp = tags.station;
                    elseif isfield(tags,'railway'), tp = tags.railway;
                    elseif isfield(tags,'highway'), tp = tags.highway;
                    end

                    % Name-based promotion (rail)
                    if contains(nmLower,"mrt") || contains(nmLower,"lrt") || contains(nmLower,"komuter") || contains(nmLower,"ktm")
                        prio = 2;
                        tp   = "rail";
                    end

                    s.id = string(e.type) + ":" + string(e.id);
                    s.name = nm;
                    s.type = tp;
                    s.lat = lat;
                    s.lon = lon;
                    s.priority = prio;
                    stops(end+1) = s;
                end
            end

            function out = mergeUniqueStops(a, b)
                if isempty(a), out = b; return; end
                if isempty(b), out = a; return; end
                allStops = [a(:); b(:)];
                ids = [allStops.id];
                [~, ia] = unique(ids, 'stable');
                out = allStops(ia);
            end

            function out = limitStops(stops, maxN)
                if numel(stops) <= maxN, out = stops; return; end
                out = stops(1:maxN);
            end

            function d = roughDistanceMeters(lat0, lon0, latv, lonv)
                R = 6371000; % meters
                lat1 = deg2rad(lat0);
                lon1 = deg2rad(lon0);
                lat2 = deg2rad(latv);
                lon2 = deg2rad(lonv);
                dlat = lat2 - lat1;
                dlon = lon2 - lon1;
                a = sin(dlat/2).^2 + cos(lat1).*cos(lat2).*sin(dlon/2).^2;
                c = 2*atan2(sqrt(a), sqrt(1-a));
                d = R*c;
            end

            function [distM, durS, ok] = osrmTableFoot(srcLat, srcLon, cand)
                nC = numel(cand);
                distM = nan(nC,1);
                durS  = nan(nC,1);
                ok    = false(nC,1);

                if nC == 0, return; end

                coords = strings(nC+1,1);
                coords(1) = sprintf('%.6f,%.6f', srcLon, srcLat);
                for i = 1:nC
                    coords(i+1) = sprintf('%.6f,%.6f', cand(i).lon, cand(i).lat);
                end
                coordStr = strjoin(coords, ';');

                destIdx = join(string(1:nC), ';'); % destinations=1;2;...;n
                url = "https://router.project-osrm.org/table/v1/foot/" + coordStr;
                fullUrl = url + "?sources=0&destinations=" + string(destIdx) + "&annotations=distance,duration";

                opts = weboptions('Timeout',12,'ContentType','json','UserAgent','MATLAB Student Project');

                try
                    resp = webread(url, ...
                        'sources','0', ...
                        'destinations', char(destIdx), ...
                        'annotations','distance,duration', ...
                        opts);

                    if ~isfield(resp,'distances') || ~isfield(resp,'durations')
                        return;
                    end

                    % resp.distances is 1 x nC (from source 0 to each destination)
                    dRow = resp.distances(1,:);
                    tRow = resp.durations(1,:);

                    for i = 1:nC
                        d = dRow(i);
                        t = tRow(i);
                        if ~isnan(d) && ~isnan(t) && isfinite(d) && isfinite(t)
                            distM(i) = d;
                            durS(i)  = t;
                            ok(i)    = true;
                        end
                    end

                catch
                    % Fallback: route API for a small subset
                    fallbackN = min(nC, 8);
                    routeUrl = "https://router.project-osrm.org/route/v1/foot/";
                    for i = 1:fallbackN
                        coord2 = sprintf('%.6f,%.6f;%.6f,%.6f', srcLon, srcLat, cand(i).lon, cand(i).lat);
                        try
                            r = webread(routeUrl + coord2, 'overview','false', opts);
                            if isfield(r,'routes') && ~isempty(r.routes)
                                distM(i) = r.routes(1).distance;
                                durS(i)  = r.routes(1).duration;
                                ok(i)    = true;
                            end
                        catch
                        end
                    end
                end
            end

            function candOut = scoreAndSort(candIn)
                candOut = candIn;
                for i = 1:numel(candOut)
                    nm = lower(string(candOut(i).name));
                    bonus = 0;
                    if contains(nm,"mrt") || contains(nm,"lrt") || contains(nm,"station") || contains(nm,"komuter")
                        bonus = bonus + 100;
                    end
                    candOut(i).finalScore = (candOut(i).priority * 50) + bonus;
                end

                S = [candOut.finalScore]';
                D = [candOut.osrmDistM]';
                T = table(S, D, (1:numel(candOut))');
                T = sortrows(T, {'S', 'D'}, {'descend', 'ascend'});
                candOut = candOut(T.Var3);
            end

            function picked = pickTop3PreferRailThenBus(cand)
                picked = cand([]);
                used = strings(0,1);

                railIdx = find([cand.priority] == 2);
                for i = railIdx
                    if numel(picked) >= 3, break; end
                    if ~any(used == string(cand(i).id))
                        picked(end+1) = cand(i); %#ok<AGROW>
                        used(end+1) = string(cand(i).id); %#ok<AGROW>
                    end
                end

                if numel(picked) < 3
                    busIdx = find([cand.priority] == 1);
                    for i = busIdx
                        if numel(picked) >= 3, break; end
                        if ~any(used == string(cand(i).id))
                            picked(end+1) = cand(i); %#ok<AGROW>
                            used(end+1) = string(cand(i).id); %#ok<AGROW>
                        end
                    end
                end
            end

        end

        % Value changed function: PredictPrice
        function PredictRentalButtonClicked(app, event)
            app.PredictedPriceEditField.ValueDisplayFormat = 'RM %.2f';

            % input data validation check
            missingFields = string([]);

            if isnan(app.SizesqftEditField.Value) || app.SizesqftEditField.Value <= 0
                missingFields(end+1) = "Size";
            end

            if isnan(app.RoomSpinner.Value) || app.RoomSpinner.Value <= 0
                missingFields(end+1) = "Room";
            end

            if isnan(app.ParkingSpinner.Value)
                missingFields(end+1) = "Parking";
            end

            if isnan(app.BathroomSpinner.Value) || app.BathroomSpinner.Value <= 0
                missingFields(end+1) = "Bathroom";
            end

            % invalid information notification
            if ~isempty(missingFields)
                msg = "These information must be filled before prediction:" + newline + ...
                    strjoin(missingFields, ", ");
                uialert(app.UIFigure, msg, "Incomplete Information");
                return;
            end

            % extract all input values
            parking               = app.ParkingSpinner.Value;
            bathroom              = app.BathroomSpinner.Value;
            sz                    = app.SizesqftEditField.Value;
            furnished             = app.FurnishedDropDown.Value;
            region                = app.RegionDropDown.Value;
            rooms                 = app.RoomSpinner.Value;
            location              = app.LocationDropDown.Value;

            % extract all facilities input values
            selectedNodes = app.Tree.SelectedNodes;
            selected = string([]);

            if iscell(selectedNodes)   % multiple selection
                for i = 1:numel(selectedNodes)
                    selected(end+1) = string(selectedNodes{i}.Text);
                end

            elseif isa(selectedNodes, 'matlab.ui.container.TreeNode')   %single selection
                selected = string(selectedNodes.Text);

            else
                selected = string.empty; % no selection
            end

            f_barbeque_area      = ismember("barbeque_area",      selected);
            f_club_house         = ismember("club_house",         selected);
            f_gymnasium          = ismember("gymnasium",          selected);
            f_jogging_track      = ismember("jogging_track",      selected);
            f_lift               = ismember("lift",               selected);
            f_minimart           = ismember("minimart",           selected);
            f_multipurpose_hall  = ismember("multipurpose_hall",  selected);
            f_playground         = ismember("playground",         selected);
            f_sauna              = ismember("sauna",              selected);
            f_security           = ismember("security",           selected);
            f_squash_court       = ismember("squash_court",       selected);
            f_swimming_pool      = ismember("swimming_pool",      selected);
            f_tennis_court       = ismember("tennis_court",       selected);
            fa_air_cond          = ismember("air-cond",            selected);
            fa_internet          = ismember("internet",           selected);
            fa_washing_machine   = ismember("washing_machine",    selected);

            % construct the feature vector X (follow model training)
            X = [ ...
                location, ...
                rooms, ...
                parking, ...
                bathroom, ...
                sz, ...
                furnished, ...
                region, ...
                f_barbeque_area, ...
                f_club_house, ...
                f_gymnasium, ...
                f_jogging_track, ...
                f_lift, ...
                f_minimart, ...
                f_multipurpose_hall, ...
                f_playground, ...
                f_sauna, ...
                f_security, ...
                f_squash_court, ...
                f_swimming_pool, ...
                f_tennis_court, ...
                fa_air_cond, ...
                fa_internet, ...
                fa_washing_machine];

            % convert MATLAB array to Python numpy array
            np = py.importlib.import_module('numpy');
            xPy  = np.array(X);
            xPy2 = xPy.reshape(int32(1), int32(numel(X)));

            predPy   = app.pyRentModel.predict(xPy2);
            predList = predPy.tolist();
            pred     = double(predList{1});

            % output prediction result
            app.PredictedPriceEditField.Value = pred;
            app.MonthlyRentEditField.Value =  pred; % sync to monthly rent in investment tab

        end

        % Value changed function: Button_3
        function RunEvaluationButtonPushed(app, event)

            fields = { ...
                'MonthlyRentEditField', ...
                'PurchasePriceEditField', ...
                'MarketPriceEditField', ...
                'OperatingExpensesEditField', ...
                'MonthlyPaymentEditField', ...
                'LoanAmountEditField', ...
                'TotalCashInvestedEditField', ...
                'HoldingYearsEditField'};

            fieldNamesReadable = { ...
                'Monthly Rent', ...
                'Purchase Price', ...
                'Market Value', ...
                'Operating Expenses', ...
                'Monthly Payment', ...
                'Loan Amount', ...
                'Total Cash Invested', ...
                'Holding Years'};

            % missing check
            missingFields = string([]);

            for i = 1:numel(fields)
                value = app.(fields{i}).Value;
                if isempty(value) || isnan(value) || value <= 0
                    missingFields(end+1) = fieldNamesReadable{i};
                end
            end

            if ~isempty(missingFields)
                msg = "The following fields must be filled with valid values:" + newline + ...
                    "- " + strjoin(missingFields, newline + "- ");
                uialert(app.UIFigure, msg, "Missing or Invalid Input");
                return;
            end

            %   get values
            monthlyRent        = app.MonthlyRentEditField.Value;
            purchasePrice      = app.PurchasePriceEditField.Value;
            marketValue        = app.MarketPriceEditField.Value;
            annualOperatingExpenses  = app.OperatingExpensesEditField.Value;
            monthlyPayment     = app.MonthlyPaymentEditField.Value;
            totalCashInvested  = app.TotalCashInvestedEditField.Value;
            holdingYears       = app.HoldingYearsEditField.Value;

            safeDiv = @(num, den) num ./ max(den, 1e-6); % function to avoid division by zero
            %% metric calculations
            % NOI = annual rent amount - annualOperatingExpenses，without loan
            NOI = (monthlyRent * 12) - annualOperatingExpenses;

            annualDebtService = monthlyPayment*12;

            % annualCashFlow（before tax），
            annualCashFlow = NOI - annualDebtService;

            % Net Rental Yield
            netRentalYield = safeDiv(NOI, purchasePrice) * 100;

            % Cash-on-Cash Return
            cashFlowROI = safeDiv(annualCashFlow, totalCashInvested) * 100;

            % ROI（cash flow + capital gain）
            capitalGain = marketValue - purchasePrice;
            totalCashFlowReceived = (annualCashFlow * holdingYears);
            ROI = safeDiv((totalCashFlowReceived + capitalGain), totalCashInvested) * 100;

            % Payback Period
            paybackPeriodYears = safeDiv(totalCashInvested, annualCashFlow);

            % Cap Rate
            capRate = safeDiv(NOI, marketValue) * 100;

            % Cash-on-Cash Return
            cashOnCash = cashFlowROI;

            % DSCR
            DSCR = safeDiv(NOI, annualDebtService);

            OperatingExpenseRatio = safeDiv(annualOperatingExpenses, (monthlyRent*12));

            breakEvenOccupancy = safeDiv((annualOperatingExpenses + annualDebtService), (monthlyRent*12));

            EquityMultiple = safeDiv((annualCashFlow * holdingYears + capitalGain), totalCashInvested);

            LTV = safeDiv(app.LoanAmountEditField.Value, purchasePrice);

            NOI_margin = safeDiv(NOI, purchasePrice);

            % Annualized Appreciation Rate
            if holdingYears > 0 && marketValue > 0 && purchasePrice > 0
                appreciationFactor = safeDiv(marketValue, purchasePrice);
                annualAppreciation = (power(appreciationFactor, 1/holdingYears) - 1) * 100;
            else
                annualAppreciation = 0;
            end


            %cards
            app.NOIValueLabel.Text = {
                'NOI'
                sprintf('RM %.0f /yr', NOI)
                };

            app.NOIValueLabel_2.Text = {
                'Cash Flow'
                sprintf('RM %.0f /yr', annualCashFlow)
                };

            app.NOIValueLabel_3.Text = {
                'Net Rental Yield'
                sprintf('%.2f%%', netRentalYield)
                };

            app.NOIValueLabel_4.Text = {
                'Cap Rate'
                sprintf('%.2f%%', capRate)
                };

            app.NOIValueLabel_5.Text = {
                'Cash-on-Cash ROI'
                sprintf('%.2f%%', cashFlowROI)
                };

            app.NOIValueLabel_6.Text = {
                'Equity Multiple'
                sprintf('%.2fx', EquityMultiple)
                };

            app.NOIValueLabel_7.Text = {
                'Payback'
                sprintf('%.1f years', paybackPeriodYears)
                };

            app.NOIValueLabel_8.Text = {
                'Total ROI'
                sprintf('%.2f%%', ROI)
                };

            app.NOIValueLabel_9.Text = {
                'DSCR'
                sprintf('%.2f', DSCR)
                };

            app.NOIValueLabel_10.Text = {
                'LTV'
                sprintf('%.2f%%', LTV * 100)
                };

            app.NOIValueLabel_11.Text = {
                'Break-even Occupancy Ratio'
                sprintf('%.1f%%', breakEvenOccupancy* 100)
                };
            app.NOIValueLabel_12.Text = {
                'Annual Appreciation '
                sprintf('%.1f%%', annualAppreciation)
                };

            %% Charts
            % DSCR bar chart
            axes(app.UIAxes7_3);
            cla(app.UIAxes7_3);

            bar(app.UIAxes7_3, 1, DSCR);
            ylim(app.UIAxes7_3, [0 2]);
            xlim(app.UIAxes7_3, [0 2]);
            set(app.UIAxes7_3, 'XTick', [], 'Box', 'off');

            title(app.UIAxes7_3, sprintf('DSCR = %.2f', DSCR));

            hold(app.UIAxes7_3, 'on');
            plot(app.UIAxes7_3, [0 2], [1 1], '--r');
            hold(app.UIAxes7_3, 'off');

            %% LTV bar chart
            axes(app.UIAxes7_4);
            cla(app.UIAxes7_4);

            b = bar(app.UIAxes7_4, 1, LTV*100);
            ylim(app.UIAxes7_4, [0 100]);
            xlim(app.UIAxes7_4, [0 2]);
            set(app.UIAxes7_4, 'XTick', [], 'Box', 'off');

            title(app.UIAxes7_4, sprintf('LTV = %.1f%%', LTV));

            hold(app.UIAxes7_4, 'on');

            % 80% safe line
            plot(app.UIAxes7_4, [0 2], [80 80], '--r');

            if LTV <= 80
                b.FaceColor = [0.2 0.7 0.3];
            else
                b.FaceColor = [0.85 0.33 0.10];
            end

            hold(app.UIAxes7_4, 'off');

            % Break-even Occupancy Donut

            beOcc = min(max(breakEvenOccupancy, 0), 1);
            dataBE = [beOcc, 1 - beOcc];

            axes(app.UIAxes7_5);
            cla(app.UIAxes7_5);

            p = pie(app.UIAxes7_5, dataBE);
            title(app.UIAxes7_5, 'Break-even Occupancy');

            try
                p(2).String = sprintf('Break-even: %.1f%%', beOcc * 100);
                p(4).String = sprintf('Buffer: %.1f%%', (1 - beOcc) * 100);
            end

            % Operating Expense Ratio Donut

            oer = min(max(OperatingExpenseRatio, 0), 1);
            dataOER = [oer, 1 - oer];

            axes(app.UIAxes7_6);
            cla(app.UIAxes7_6);

            p2 = pie(app.UIAxes7_6, dataOER);
            title(app.UIAxes7_6, 'Operating Expense Ratio');

            try
                p2(2).String = sprintf('Opex: %.1f%%', oer * 100);
                p2(4).String = sprintf('Net: %.1f%%', (1 - oer) * 100);
            end

            % Annual Cash Flow Trend

            totalYears = max(round(holdingYears), 1);
            allYears   = 1:totalYears;

            cumulativeCashFlow = cumsum(annualCashFlow * ones(size(allYears)));

            MAX_PLOTS = 10;
            if totalYears <= MAX_PLOTS
                plotIdx = allYears;
            else
                plotIdx = unique(round(linspace(1, totalYears, MAX_PLOTS))); % unique 防止重复年份
            end

            yearsToPlot = allYears(plotIdx);
            cashToPlot  = cumulativeCashFlow(plotIdx);

            axes(app.UIAxes7);
            cla(app.UIAxes7);

            plot(app.UIAxes7, yearsToPlot, cashToPlot, '-o', 'LineWidth', 1.5, 'MarkerSize', 6);
            xlabel(app.UIAxes7, 'Year');
            ylabel(app.UIAxes7, 'Cumulative Cash Flow (RM)');
            title(app.UIAxes7, 'Cumulative Cash Flow Trend');
            grid(app.UIAxes7, 'on');

            for i = 1:numel(yearsToPlot)
                text(app.UIAxes7, yearsToPlot(i), cashToPlot(i), sprintf('RM %.0f', cashToPlot(i)), ...
                    'VerticalAlignment','bottom','HorizontalAlignment','center','FontSize',9);
            end


            % Equity / Total Return Growth

            equity = annualCashFlow * allYears;
            if ~isempty(equity)
                equity(end) = equity(end) + capitalGain;
            end

            axes(app.UIAxes7_2);
            cla(app.UIAxes7_2);

            area(app.UIAxes7_2, allYears, equity);
            xlabel(app.UIAxes7_2, 'Year');
            ylabel(app.UIAxes7_2, 'Cumulative Equity (RM)');
            title(app.UIAxes7_2, 'Equity / Total Return Growth');
            grid(app.UIAxes7_2, 'on');

            % ---------- FUZZY ACTION ----------
            % Make sure your inputs match the order exactly:
            % [Yield, CashFlowPct, DSCR, LTVPct, GainPct]

            y = min(max(netRentalYield, 0), 12);
            cashFlowPct = safeDiv(annualCashFlow, purchasePrice) * 100;
            cashFlowPct = min(max(cashFlowPct, -10), 10);

            d = min(max(DSCR, 0), 2.5);
            ltvPct = min(max(LTV*100, 0), 100);

            gainPct = safeDiv((marketValue - purchasePrice), purchasePrice) * 100;
            gainPct = min(max(gainPct, -30), 50);

            actionScore = evalfis(app.FISRules, [cashFlowPct,d,ltvPct, gainPct,y]);

            % Map numeric score to label
            if actionScore < 45
                action = "BUY";
            elseif actionScore < 65
                action = "HOLD";
            else
                action = "SELL";
            end

            %% Suggestion
            app.FuzzyResultLabel.FontSize = 20;
            app.FuzzyResultLabel.FontWeight = 'bold';
            app.FuzzyResultLabel.Text = action ;
            %set colour
            switch action
                case "BUY"
                    app.FuzzyResultLabel.FontColor = [0 0.7 0.7];
                case "HOLD"
                    app.FuzzyResultLabel.FontColor = [1 0.55 0];
                case "SELL"
                    app.FuzzyResultLabel.FontColor = [1 0 0];
            end

            %% Report
            scoreProfit = 0;
            scoreRisk   = 0;
            comments    = {};

            % ===== Fuzzy Outcome =====
            comments{end+1} = '=== FUZZY DECISION (Buy / Hold / Sell) ===';
            comments{end+1} = sprintf('Action: %s', char(action));

            % Decision confidence (rename from score)
            confidence = min(max(actionScore,0),100);
            comments{end+1} = sprintf('Decision confidence: %.0f / 100', confidence);
            comments{end+1} = ' ';   % blank line

            reasons = {};

            % Risk reasons
            if d < 1.0
                reasons{end+1} = sprintf('DSCR is %.2f (< 1.0), meaning NOI does not cover debt service (high default/refinancing risk).', d);
            end
            if ltvPct >= 80
                reasons{end+1} = sprintf('LTV is %.1f%% (high leverage), increasing sensitivity to rent/price/interest changes.', ltvPct);
            end

            % Return / cashflow reasons
            if cashFlowPct < 0
                reasons{end+1} = sprintf('Cash flow is negative (%.2f%% of purchase price), implying ongoing holding losses.', cashFlowPct);
            end
            if y < 4
                reasons{end+1} = sprintf('Net rental yield is low (%.2f%%), indicating weak income return.', y);
            end

            % Gain context
            if gainPct > 0 && (d < 1.0 || cashFlowPct < 0)
                reasons{end+1} = sprintf('Despite a %.1f%% gain, risk indicators dominate; exiting may prevent forced selling later.', gainPct);
            elseif gainPct < 0
                reasons{end+1} = sprintf('Current gain is negative (%.1f%%), which weakens the case for holding unless fundamentals improve.', gainPct);
            end

            comments{end+1} = 'Reason(s):';
            for i = 1:numel(reasons)
                comments{end+1} = sprintf(' - %s', reasons{i});
            end
            comments{end+1} = ' ';   % blank line

            %% Profitability section
            comments{end+1} = '--- Profitability Metrics ---';

            %% Net Rental Yield
            if netRentalYield < 4
                bandText = 'which is below 4% and typically considered a relatively weak income return for residential property.';
            elseif netRentalYield <= 6
                bandText = 'which falls between 4% and 6%, a range often viewed as acceptable but not particularly high.';
                scoreProfit = scoreProfit + 1;
            else
                bandText = 'which exceeds 6% and indicates a comparatively strong income return, subject to the sustainability of rents and occupancy.';
                scoreProfit = scoreProfit + 2;
            end
            comments{end+1} = sprintf(['Net rental yield measures annual net rental income as a percentage of the property value. ', ...
                'In this scenario, the yield is %.2f%%, %s'], netRentalYield, bandText);

            %% Equity Multiple
            if EquityMultiple < 1.0
                bandText = 'suggesting that, based on the current assumptions, the project may not fully recover the initial equity over the holding period.';
            elseif EquityMultiple < 1.5
                bandText = 'indicating that the project broadly approaches capital recovery but delivers only modest growth beyond the initial equity.';
            elseif EquityMultiple < 2.0
                bandText = 'indicating that the investment generates a balanced level of total return over the holding period.';
                scoreProfit = scoreProfit + 1;
            else
                bandText = 'indicating a relatively high total return multiple, although this also depends on the assumed holding period and exit conditions.';
                scoreProfit = scoreProfit + 2;
            end
            comments{end+1} = sprintf(['Equity multiple captures how many times the initial equity investment is returned over the full holding period. ', ...
                'Here, the equity multiple is %.2fx, %s'], EquityMultiple, bandText);

            %% Cash-on-Cash Return
            if cashFlowROI     < 8
                bandText = 'which points to a relatively modest level of annual cash return relative to the equity invested.';
            elseif cashFlowROI     <= 12
                bandText = 'which lies between 8%% and 12%% and is commonly regarded as a healthy range for income-focused investors.';
                scoreProfit = scoreProfit + 1;
            else
                bandText = 'which exceeds 12%% and suggests a strong cash yield, provided that the assumptions on rent, occupancy, and expenses are realistic.';
                scoreProfit = scoreProfit + 2;
            end
            comments{end+1} = sprintf(['Cash-on-cash return measures annual pre-tax cash flow as a percentage of the equity invested. ', ...
                'In this case, the cash-on-cash return is %.2f%%, %s'], cashFlowROI    , bandText);

            %% Payback Period
            if paybackPeriodYears <= 8
                bandText = 'indicating relatively fast capital recovery from operating cash flows.';
                scoreProfit = scoreProfit + 2;
            elseif paybackPeriodYears <= 15
                bandText = 'which is consistent with a typical medium-term recovery horizon for residential investments.';
                scoreProfit = scoreProfit + 1;
            else
                bandText = 'suggesting that equity recovery relies on a longer holding period and possibly on capital appreciation.';
            end
            comments{end+1} = sprintf(['The payback period estimates how many years are required to recover the initial equity from net cash flows. ', ...
                'Here, the payback period is approximately %.1f years, %s'], paybackPeriodYears, bandText);

            %% Cap Rate
            if capRate < 5
                bandText = 'which is relatively low and may reflect a premium valuation or reliance on future growth expectations.';
            elseif capRate <= 10
                bandText = 'which falls within the commonly cited 5%%–10%% range, suggesting that income-based valuation is broadly in line with market norms.';
                scoreProfit = scoreProfit + 1;
            else
                bandText = 'which is comparatively high and may indicate elevated risk or a discounted purchase price.';
                scoreProfit = scoreProfit + 1;
            end
            comments{end+1} = sprintf(['The capitalization rate compares NOI to property value and reflects income-based valuation. ', ...
                'In this analysis, the cap rate is %.2f%%, %s'], capRate, bandText);

            %% Total ROI
            if ROI < 5
                bandText = 'indicating a relatively modest overall return once both income and capital gains are considered.';
            elseif ROI <= 10
                bandText = 'suggesting a moderate and potentially more stable overall return profile.';
                scoreProfit = scoreProfit + 1;
            else
                bandText = 'indicating a comparatively strong total return, which should be cross-checked against conservative assumptions.';
                scoreProfit = scoreProfit + 2;
            end
            comments{end+1} = sprintf(['Total ROI combines income and capital gain as a percentage of the initial equity. ', ...
                'Here, the total ROI is %.2f%%%%, %s'], ROI, bandText);

            %% Risk & Debt Safety section
            comments{end+1} = sprintf('\n--- Risk and Debt Safety Metrics ---');

            %% DSCR
            if DSCR < 1.0
                bandText = 'meaning that projected NOI does not fully cover annual debt service, which heightens refinancing and default risk.';
            elseif DSCR < 1.25
                bandText = 'indicating that NOI only marginally covers debt service, leaving limited buffer against cash flow volatility.';
                scoreRisk = scoreRisk + 1;
            else
                bandText = 'suggesting a more comfortable coverage of debt service, with additional resilience against income fluctuations.';
                scoreRisk = scoreRisk + 2;
            end
            comments{end+1} = sprintf(['The debt service coverage ratio (DSCR) measures how many times NOI can cover annual loan payments. ', ...
                'In this case, DSCR is %.2f, %s'], DSCR, bandText);

            %% LTV
            if LTV <= 0.80
                bandText = 'which indicates a moderate leverage level and a reasonable equity buffer against potential value declines.';
                scoreRisk = scoreRisk + 2;
            elseif LTV <= 0.90
                bandText = 'which reflects relatively high leverage and greater sensitivity to market price movements.';
                scoreRisk = scoreRisk + 1;
            else
                bandText = 'which suggests very high leverage and a limited margin of safety if asset values or rents weaken.';
            end
            comments{end+1} = sprintf(['The loan-to-value (LTV) ratio compares loan amount to property value and reflects the degree of financial leverage. ', ...
                'In this scenario, the LTV is %.0f%%%%, %s'], LTV*100, bandText);

            %% NOI
            if exist('NOI_margin','var')
                if NOI_margin < 5
                    bandText = 'indicating relatively weak operating income relative to the investment base.';
                elseif NOI_margin <= 10
                    bandText = 'which lies within a reasonable range for many income-producing assets.';
                    scoreProfit = scoreProfit + 1;
                elseif NOI_margin <= 15
                    bandText = 'suggesting strong operating performance with healthy income relative to cost.';
                    scoreProfit = scoreProfit + 2;
                else
                    bandText = 'indicating very strong operating profitability, subject to the sustainability of current rents and expenses.';
                    scoreProfit = scoreProfit + 2;
                end
                comments{end+1} = sprintf(['NOI margin reflects how much operating income is generated relative to the investment cost or value. ', ...
                    'In this case, the margin is %.2f%%%%, %s'], NOI_margin*100, bandText);
            end

            %% Overall narrative summary
            if scoreProfit >= 7 && scoreRisk >= 4
                overall = ['Overall Assessment: Taken together, the profitability and risk indicators suggest that the asset exhibits a generally favourable financial profile under the stated assumptions, ', ...
                    'with both income generation and debt coverage aligning reasonably well with commonly cited benchmarks. Actual performance, however, will still depend on market conditions, tenant stability, and execution.'];
            elseif scoreProfit >= 4 && scoreRisk >= 2
                overall = ['Overall Assessment: The metrics point to a broadly balanced investment profile. ', ...
                    'Profitability appears acceptable, while leverage and coverage levels warrant ongoing monitoring but do not immediately indicate excessive risk. Refinement of assumptions or negotiation on pricing/financing could further strengthen the case.'];
            else
                overall = ['Overall Assessment: Several indicators fall on the more conservative or stretched side of typical benchmark ranges. ', ...
                    'This does not automatically rule out the investment, but it suggests that returns may be more sensitive to adverse movements in rents, occupancy, or financing terms. Additional scenario analysis would be advisable before committing capital.'];
            end

            comments{end+1} = sprintf('\n%s', overall);app.SuggestionTextArea.Value = comments;

        end

        % Button pushed function: ExportPdfButton
        function ExportPDFExportPdfButtonPushed(app, event)
            txtLines = app.SuggestionTextArea.Value;

            maxChars = 80; % maximum number of characters allowed per line
            wrappedLines = strings(0,1);% container for storing wrapped lines

            for i = 1:numel(txtLines)
                line = string(txtLines{i});
                if strlength(line) == 0
                    wrappedLines(end+1,1) = "";
                    continue;
                end

                idx = 1;
                L = strlength(line);

                % split line
                while idx <= L
                    endIdx = min(idx + maxChars - 1, L);
                    seg = extractBetween(line, idx, endIdx);
                    wrappedLines(end+1,1) = seg;
                    idx = endIdx + 1;
                end
            end

            wrappedText = strjoin(wrappedLines, newline);

            if ispc
                downloadsFolder = fullfile(getenv('USERPROFILE'), 'Downloads');
            else
                downloadsFolder = fullfile(getenv('HOME'), 'Downloads');
            end

            filename = fullfile(downloadsFolder, 'Investment_Report.pdf');

            % create an invisible figure used as a PDF render canvas
            f = figure('Visible', 'off', ...
                'PaperPositionMode', 'auto', ...
                'Units', 'normalized', ...
                'Position', [0.1 0.1 0.8 0.8]);
            ax = axes('Parent', f, ...
                'Position', [0 0 1 1], ...
                'Visible', 'off');
            text(0, 1, char(wrappedText), ...
                'Parent', ax, ...
                'VerticalAlignment', 'top', ...
                'Interpreter', 'none', ...
                'FontName', 'Consolas', ...
                'FontSize', 10);
            axis(ax, 'off');
            % export to PDF
            try
                exportgraphics(f, filename, 'ContentType', 'vector');
            catch
                print(f, filename, '-dpdf', '-bestfit');
            end
            close(f);
            uialert(app.UIFigure, ...
                sprintf('Analysis report has been saved to:\n%s', filename), ...
                'Export Complete');
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.ThemeTool = uipushtool(app.Toolbar);
            app.ThemeTool.ClickedCallback = createCallbackFcn(app, @ThemeToolClicked, true);
            app.ThemeTool.Icon = fullfile(pathToMLAPP, 'pictures', 'theme.jpg');
            app.HelpTool = uipushtool(app.Toolbar);
            app.HelpTool.ClickedCallback = createCallbackFcn(app, @HelpToolClicked, true);
            app.HelpTool.Icon = fullfile(pathToMLAPP, 'pictures', 'info.jpg');
            app.DemoTool = uipushtool(app.Toolbar);
            app.DemoTool.ClickedCallback = createCallbackFcn(app, @DemoToolClicked, true);
            app.DemoTool.Icon = fullfile(pathToMLAPP, 'pictures', 'demo.png');
            app.RefreshTool = uipushtool(app.Toolbar);
            app.RefreshTool.ClickedCallback = createCallbackFcn(app, @RefreshToolClicked, true);
            app.RefreshTool.Icon = fullfile(pathToMLAPP, 'pictures', 'refresh.jpg');
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Tooltip = {'Rental Price Estimator'};
            app.RentEstimatorTab = uitab(app.TabGroup);
            app.RentEstimatorTab.Tooltip = {'Rental Price Estimator'};
            app.RentEstimatorTab.Title = 'Rent Estimator';
            app.GridLayout6 = uigridlayout(app.RentEstimatorTab);
            app.Tree = uitree(app.GridLayout6, 'checkbox');
            app.FacilitiesNode = uitreenode(app.Tree);
            app.FacilitiesNode.Text = 'Facilities';
            app.barbeque_areaNode = uitreenode(app.FacilitiesNode);
            app.barbeque_areaNode.Text = 'barbeque_area';
            app.club_houseNode = uitreenode(app.FacilitiesNode);
            app.club_houseNode.Text = 'club_house';
            app.gymnasiumNode = uitreenode(app.FacilitiesNode);
            app.gymnasiumNode.Text = 'gymnasium';
            app.jogging_trackNode = uitreenode(app.FacilitiesNode);
            app.jogging_trackNode.Text = 'jogging_track';
            app.liftNode = uitreenode(app.FacilitiesNode);
            app.liftNode.Text = 'lift';
            app.minimartNode = uitreenode(app.FacilitiesNode);
            app.minimartNode.Text = 'minimart';
            app.multipurpose_hallNode = uitreenode(app.FacilitiesNode);
            app.multipurpose_hallNode.Text = 'multipurpose_hall';
            app.playgroundNode = uitreenode(app.FacilitiesNode);
            app.playgroundNode.Text = 'playground';
            app.saunaNode = uitreenode(app.FacilitiesNode);
            app.saunaNode.Text = 'sauna';
            app.securityNode = uitreenode(app.FacilitiesNode);
            app.securityNode.Text = 'security';
            app.washing_machineNode = uitreenode(app.FacilitiesNode);
            app.washing_machineNode.Text = 'washing_machine';
            app.squash_courtNode = uitreenode(app.FacilitiesNode);
            app.squash_courtNode.Text = 'squash_court';
            app.swimming_poolNode = uitreenode(app.FacilitiesNode);
            app.swimming_poolNode.Text = 'swimming_pool';
            app.tennis_courtNode = uitreenode(app.FacilitiesNode);
            app.tennis_courtNode.Text = 'tennis_court';
            app.aircondNode = uitreenode(app.FacilitiesNode);
            app.aircondNode.Text = 'air-cond';
            app.internetNode = uitreenode(app.FacilitiesNode);
            app.internetNode.Text = 'internet';
            app.PredictPrice = uibutton(app.GridLayout6, 'state');
            app.PredictPrice.ValueChangedFcn = createCallbackFcn(app, @PredictRentalButtonClicked, true);
            app.PredictPrice.Text = 'Predict Price';
            app.ParkingSpinnerLabel = uilabel(app.GridLayout6);
            app.ParkingSpinnerLabel.Text = 'Parking';
            app.ParkingSpinner = uispinner(app.GridLayout6);
            app.ParkingSpinner.Limits = [0 Inf];
            app.BathroomSpinnerLabel = uilabel(app.GridLayout6);
            app.BathroomSpinnerLabel.Text = 'Bathroom';
            app.BathroomSpinner = uispinner(app.GridLayout6);
            app.BathroomSpinner.Limits = [0 Inf];
            app.LocationDropDownLabel = uilabel(app.GridLayout6);
            app.LocationDropDownLabel.Text = 'Location';
            app.LocationDropDown = uidropdown(app.GridLayout6);
            app.LocationDropDown.Items = {'Kuala Lumpur - Ampang', 'Kuala Lumpur - Ampang Hilir', 'Kuala Lumpur - Bandar Damai Perdana', 'Kuala Lumpur - Bandar Menjalara', 'Kuala Lumpur - Bandar Tasik Selatan', 'Kuala Lumpur - Bangsar', 'Kuala Lumpur - Bangsar South', 'Kuala Lumpur - Brickfields', 'Kuala Lumpur - Bukit Bintang', 'Kuala Lumpur - Bukit Jalil', 'Kuala Lumpur - Bukit Tunku', 'Kuala Lumpur - Chan Sow Lin', 'Kuala Lumpur - Cheras', 'Kuala Lumpur - City Centre', 'Kuala Lumpur - Country Heights Damansara', 'Kuala Lumpur - Damansara', 'Kuala Lumpur - Damansara Heights', 'Kuala Lumpur - Desa Pandan', 'Kuala Lumpur - Desa ParkCity', 'Kuala Lumpur - Desa Petaling', 'Kuala Lumpur - Gombak', 'Kuala Lumpur - Jalan Ipoh', 'Kuala Lumpur - Jalan Kuching', 'Kuala Lumpur - Jalan Sultan Ismail', 'Kuala Lumpur - Jinjang', 'Kuala Lumpur - KL City', 'Kuala Lumpur - KL Sentral', 'Kuala Lumpur - KLCC', 'Kuala Lumpur - Kampung Baru', 'Kuala Lumpur - Kepong', 'Kuala Lumpur - Keramat', 'Kuala Lumpur - Kuchai Lama', 'Kuala Lumpur - Mid Valley City', 'Kuala Lumpur - Mont Kiara', 'Kuala Lumpur - OUG', 'Kuala Lumpur - Old Klang Road', 'Kuala Lumpur - Others', 'Kuala Lumpur - Pandan Indah', 'Kuala Lumpur - Pandan Jaya', 'Kuala Lumpur - Pandan Perdana', 'Kuala Lumpur - Pantai', 'Kuala Lumpur - Puchong', 'Kuala Lumpur - Pudu', 'Kuala Lumpur - Salak Selatan', 'Kuala Lumpur - Segambut', 'Kuala Lumpur - Sentul', 'Kuala Lumpur - Seputeh', 'Kuala Lumpur - Serdang', 'Kuala Lumpur - Setapak', 'Kuala Lumpur - Setiawangsa', 'Kuala Lumpur - Solaris Dutamas', 'Kuala Lumpur - Sri Damansara', 'Kuala Lumpur - Sri Hartamas', 'Kuala Lumpur - Sri Petaling', 'Kuala Lumpur - Sungai Besi', 'Kuala Lumpur - Taman Desa', 'Kuala Lumpur - Taman Melawati', 'Kuala Lumpur - Taman Permata', 'Kuala Lumpur - Taman Tun Dr Ismail', 'Kuala Lumpur - Titiwangsa', 'Kuala Lumpur - Wangsa Maju', 'Penang - Batu Kawan', 'Penang - Bukit Mertajam', 'Penang - Gelugor', 'Penang - Simpang Ampat', 'Selangor - Alam Impian', 'Selangor - Ampang', 'Selangor - Ara Damansara', 'Selangor - Balakong', 'Selangor - Bandar Botanic', 'Selangor - Bandar Bukit Raja', 'Selangor - Bandar Bukit Tinggi', 'Selangor - Bandar Kinrara', 'Selangor - Bandar Mahkota Cheras', 'Selangor - Bandar Puteri Klang', 'Selangor - Bandar Saujana Putra', 'Selangor - Bandar Sri Damansara', 'Selangor - Bandar Sungai Long', 'Selangor - Bandar Sunway', 'Selangor - Bandar Utama', 'Selangor - Bangi', 'Selangor - Banting', 'Selangor - Batu Caves', 'Selangor - Beranang', 'Selangor - Bukit Beruntung', 'Selangor - Bukit Jelutong', 'Selangor - Bukit Subang', 'Selangor - Cheras', 'Selangor - Cyberjaya', 'Selangor - Damansara Damai', 'Selangor - Damansara Perdana', 'Selangor - Damansara Utama', 'Selangor - Denai Alam', 'Selangor - Dengkil', 'Selangor - Glenmarie', 'Selangor - Gombak', 'Selangor - Hulu Langat', 'Selangor - I-City', 'Selangor - Jenjarom', 'Selangor - Kajang', 'Selangor - Kapar', 'Selangor - Kelana Jaya', 'Selangor - Klang', 'Selangor - Kota Damansara', 'Selangor - Kota Kemuning', 'Selangor - Kuala Langat', 'Selangor - Kuala Selangor', 'Selangor - Mutiara Damansara', 'Selangor - Petaling Jaya', 'Selangor - Port Klang', 'Selangor - Puchong', 'Selangor - Puchong South', 'Selangor - Pulau Indah (Pulau Lumut)', 'Selangor - Puncak Alam', 'Selangor - Puncak Jalil', 'Selangor - Putra Heights', 'Selangor - Rawang', 'Selangor - Saujana Utama', 'Selangor - Selayang', 'Selangor - Semenyih', 'Selangor - Sepang', 'Selangor - Serdang', 'Selangor - Serendah', 'Selangor - Seri Kembangan', 'Selangor - Setia Alam', 'Selangor - Shah Alam', 'Selangor - Subang Bestari', 'Selangor - Subang Jaya', 'Selangor - Sungai Buloh', 'Selangor - USJ', 'Selangor - Ulu Klang'};
            app.LocationDropDown.Value = 'Selangor - Cheras';
            app.RegionDropDownLabel = uilabel(app.GridLayout6);
            app.RegionDropDownLabel.Text = 'Region';
            app.RegionDropDown = uidropdown(app.GridLayout6);
            app.RegionDropDown.Items = {'Kuala Lumpur', 'Penang', 'Selangor'};
            app.RegionDropDown.Value = 'Kuala Lumpur';
            app.RoomSpinnerLabel = uilabel(app.GridLayout6);
            app.RoomSpinnerLabel.Text = 'Room ';
            app.RoomSpinner = uispinner(app.GridLayout6);
            app.RoomSpinner.Limits = [0 Inf];
            app.SizesqftEditFieldLabel = uilabel(app.GridLayout6);
            app.SizesqftEditFieldLabel.Text = 'Size (sq ft.)';
            app.SizesqftEditField = uieditfield(app.GridLayout6, 'numeric');
            app.SizesqftEditField.Limits = [0 Inf];
            app.PropertyInformationLabel = uilabel(app.GridLayout6);
            app.PropertyInformationLabel.Text = 'Property Information';
            app.FurnishedDropDownLabel = uilabel(app.GridLayout6);
            app.FurnishedDropDownLabel.Text = 'Furnished';
            app.FurnishedDropDown = uidropdown(app.GridLayout6);
            app.FurnishedDropDown.Items = {'Not Furnished', 'Partially Furnished', 'Fully Furnished'};
            app.FurnishedDropDown.Value = 'Not Furnished';
            app.PredictedPriceEditField_2Label = uilabel(app.GridLayout6);
            app.PredictedPriceEditField_2Label.Text = 'Predicted Price';
            app.PredictedPriceEditField = uieditfield(app.GridLayout6, 'numeric');
            app.InvestmentAnalysisDashboardTab = uitab(app.TabGroup);
            app.InvestmentAnalysisDashboardTab.Tooltip = {'Investment Analysis Dashboard'};
            app.InvestmentAnalysisDashboardTab.Title = 'Investment Analysis Dashboard';
            app.GridLayout7 = uigridlayout(app.InvestmentAnalysisDashboardTab);
            app.UIAxes7_6 = uiaxes(app.GridLayout7);
            title(app.UIAxes7_6, 'Title')
            xlabel(app.UIAxes7_6, 'X')
            ylabel(app.UIAxes7_6, 'Y')
            zlabel(app.UIAxes7_6, 'Z')
            app.UIAxes7_5 = uiaxes(app.GridLayout7);
            title(app.UIAxes7_5, 'Title')
            xlabel(app.UIAxes7_5, 'X')
            ylabel(app.UIAxes7_5, 'Y')
            zlabel(app.UIAxes7_5, 'Z')
            app.UIAxes7_4 = uiaxes(app.GridLayout7);
            title(app.UIAxes7_4, 'Title')
            xlabel(app.UIAxes7_4, 'X')
            ylabel(app.UIAxes7_4, 'Y')
            zlabel(app.UIAxes7_4, 'Z')
            app.UIAxes7_3 = uiaxes(app.GridLayout7);
            title(app.UIAxes7_3, 'Title')
            xlabel(app.UIAxes7_3, 'X')
            ylabel(app.UIAxes7_3, 'Y')
            zlabel(app.UIAxes7_3, 'Z')
            app.UIAxes7_2 = uiaxes(app.GridLayout7);
            title(app.UIAxes7_2, 'Title')
            xlabel(app.UIAxes7_2, 'X')
            ylabel(app.UIAxes7_2, 'Y')
            zlabel(app.UIAxes7_2, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout7);
            title(app.UIAxes7, 'Title')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.Panel_10 = uipanel(app.GridLayout7);
            app.NOIValueLabel_9 = uilabel(app.Panel_10);
            app.NOIValueLabel_9.Text = '-';
            app.Panel_13 = uipanel(app.GridLayout7);
            app.NOIValueLabel_10 = uilabel(app.Panel_13);
            app.NOIValueLabel_10.Text = '-';
            app.Panel_11 = uipanel(app.GridLayout7);
            app.NOIValueLabel_11 = uilabel(app.Panel_11);
            app.NOIValueLabel_11.Text = '-';
            app.Panel_12 = uipanel(app.GridLayout7);
            app.NOIValueLabel_12 = uilabel(app.Panel_12);
            app.NOIValueLabel_12.Text = '-';
            app.Panel_2 = uipanel(app.GridLayout7);
            app.GridLayout8 = uigridlayout(app.Panel_2);
            app.Button_3 = uibutton(app.GridLayout8, 'state');
            app.Button_3.ValueChangedFcn = createCallbackFcn(app, @RunEvaluationButtonPushed, true);
            app.Button_3.Text = 'Run Evaluation';
            app.MonthlyRentEditFieldLabel = uilabel(app.GridLayout8);
            app.MonthlyRentEditFieldLabel.Text = 'Monthly Rent';
            app.MonthlyRentEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MonthlyRentEditField.Limits = [0 Inf];
            app.MonthlyRentEditField.ValueDisplayFormat = '%.2f';
            app.PurchasePriceEditFieldLabel = uilabel(app.GridLayout8);
            app.PurchasePriceEditFieldLabel.Text = 'Purchase Price';
            app.PurchasePriceEditField = uieditfield(app.GridLayout8, 'numeric');
            app.PurchasePriceEditField.Limits = [0 Inf];
            app.PurchasePriceEditField.ValueDisplayFormat = '%.2f';
            app.MarketPriceEditFieldLabel = uilabel(app.GridLayout8);
            app.MarketPriceEditFieldLabel.Text = 'Market Price';
            app.MarketPriceEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MarketPriceEditField.Limits = [0 Inf];
            app.MarketPriceEditField.ValueDisplayFormat = '%.2f';
            app.OperatingExpensesyrLabel = uilabel(app.GridLayout8);
            app.OperatingExpensesyrLabel.Tooltip = {'✅ INCLUDED: Property Management Fees ，Maintenance & Repairs， Property Taxes， Insurance， Utilities paid by owner， Strata/Management Fees， Professional Fees '; '❌ EXCLUDED: Mortgage Payments， Depreciation， Income Tax， Capital Improvements '};
            app.OperatingExpensesyrLabel.Text = 'Operating Expenses/yr';
            app.OperatingExpensesEditField = uieditfield(app.GridLayout8, 'numeric');
            app.OperatingExpensesEditField.Limits = [0 Inf];
            app.OperatingExpensesEditField.ValueDisplayFormat = '%.2f';
            app.MonthlyPaymentEditFieldLabel = uilabel(app.GridLayout8);
            app.MonthlyPaymentEditFieldLabel.Text = 'Monthly Payment';
            app.MonthlyPaymentEditField = uieditfield(app.GridLayout8, 'numeric');
            app.MonthlyPaymentEditField.Limits = [0 Inf];
            app.MonthlyPaymentEditField.ValueDisplayFormat = '%.2f';
            app.TotalLoanAmountEditFieldLabel = uilabel(app.GridLayout8);
            app.TotalLoanAmountEditFieldLabel.Text = 'Total Loan Amount';
            app.LoanAmountEditField = uieditfield(app.GridLayout8, 'numeric');
            app.LoanAmountEditField.Limits = [0 Inf];
            app.LoanAmountEditField.ValueDisplayFormat = '%.2f';
            app.TotalCashInvestedEditFieldLabel = uilabel(app.GridLayout8);
            app.TotalCashInvestedEditFieldLabel.Tooltip = {'✅ INCLUDED: Down Payment, Renovation Costs, Legal/Closing Fees, Stamp Duty, Agent Commission,  Furniture'; '❌ EXCLUDED: Loan Amount'};
            app.TotalCashInvestedEditFieldLabel.Text = 'Total Cash Invested';
            app.TotalCashInvestedEditField = uieditfield(app.GridLayout8, 'numeric');
            app.TotalCashInvestedEditField.Limits = [0 Inf];
            app.TotalCashInvestedEditField.ValueDisplayFormat = '%.2f';
            app.HoldingYearsEditFieldLabel = uilabel(app.GridLayout8);
            app.HoldingYearsEditFieldLabel.Text = 'Holding Years';
            app.HoldingYearsEditField = uieditfield(app.GridLayout8, 'numeric');
            app.HoldingYearsEditField.Limits = [0 Inf];
            app.HoldingYearsEditField.ValueDisplayFormat = '%.0f';
            app.SuggestionTextAreaLabel = uilabel(app.GridLayout8);
            app.SuggestionTextAreaLabel.Text = 'Suggestion:';
            app.SuggestionTextArea = uitextarea(app.GridLayout8);
            app.ExportPdfButton = uibutton(app.GridLayout8, 'push');
            app.ExportPdfButton.ButtonPushedFcn = createCallbackFcn(app, @ExportPDFExportPdfButtonPushed, true);
            app.ExportPdfButton.Text = 'Export PDF';
            app.FuzzyResultLabel = uilabel(app.GridLayout8);
            app.FuzzyResultLabel.Text = '-';
            app.Panel_5 = uipanel(app.GridLayout7);
            app.NOIValueLabel = uilabel(app.Panel_5);
            app.NOIValueLabel.Text = '-';
            app.Panel_6 = uipanel(app.GridLayout7);
            app.NOIValueLabel_2 = uilabel(app.Panel_6);
            app.NOIValueLabel_2.Text = '-';
            app.Panel_14 = uipanel(app.GridLayout7);
            app.NOIValueLabel_3 = uilabel(app.Panel_14);
            app.NOIValueLabel_3.Text = '-';
            app.Panel_9 = uipanel(app.GridLayout7);
            app.NOIValueLabel_4 = uilabel(app.Panel_9);
            app.NOIValueLabel_4.Text = '-';
            app.Panel_7 = uipanel(app.GridLayout7);
            app.NOIValueLabel_5 = uilabel(app.Panel_7);
            app.NOIValueLabel_5.Text = '-';
            app.Panel_3 = uipanel(app.GridLayout7);
            app.NOIValueLabel_6 = uilabel(app.Panel_3);
            app.NOIValueLabel_6.Text = '-';
            app.Panel_4 = uipanel(app.GridLayout7);
            app.NOIValueLabel_7 = uilabel(app.Panel_4);
            app.NOIValueLabel_7.Text = '-';
            app.Panel_8 = uipanel(app.GridLayout7);
            app.NOIValueLabel_8 = uilabel(app.Panel_8);
            app.NOIValueLabel_8.Text = '-';
            app.NearbyTransportTab = uitab(app.TabGroup);
            app.NearbyTransportTab.Tooltip = {'Nearby Transport'};
            app.NearbyTransportTab.Title = 'Nearby Transport';
            app.GridLayout9 = uigridlayout(app.NearbyTransportTab);
            app.UIAxes = uiaxes(app.GridLayout9);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.GridLayout9);
            app.UITable.ColumnName = {'No.'; 'Public Transport'; 'Distance (meters)'; 'Time (min)'; 'Walking Time (min)'};
            app.UITable.RowName = {};
            app.SearchStatusLabel = uilabel(app.GridLayout9);
            app.SearchStatusLabel.Text = 'Search Status';
            app.SearchButton = uibutton(app.GridLayout9, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.BuildingNameEditFieldLabel = uilabel(app.GridLayout9);
            app.BuildingNameEditFieldLabel.Text = 'Building Name :';
            app.BuildingNameEditField = uieditfield(app.GridLayout9, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FYP_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end